classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RRvalue                    matlab.ui.control.Label
        RRLabel                    matlab.ui.control.Label
        HRvalue                    matlab.ui.control.Label
        HRLabel                    matlab.ui.control.Label
        ReadingSwitch              matlab.ui.control.Switch
        ReadingSwitchLabel         matlab.ui.control.Label
        InterferometerButtonGroup  matlab.ui.container.ButtonGroup
        MZIButton                  matlab.ui.control.RadioButton
        SagnacButton               matlab.ui.control.RadioButton
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReadingSwitch
        function ReadingSwitchValueChanged(app, event)
            value = app.ReadingSwitch.Value;
            
            if value == "On"
                app.InterferometerButtonGroup.Enable = 'off';

                % Placeholder for the ni daq setting
                dq = daq("ni");
                if app.SagnacButton.Value
                    addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
                else
                    addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
                    addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
                    addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
                end
                dq.Rate = 5000;

                % Run daq card
                scanData = read(dq,seconds(30));

                if app.SagnacButton.Value
                    app.HRvalue.Text = string(100);
                    app.RRvalue.Text = string(100);
                else
                    app.HRvalue.Text = string(200);
                    app.RRvalue.Text = string(200);
                end
            else
                app.InterferometerButtonGroup.Enable = 'on';
                app.HRvalue.Text = "-";
                app.RRvalue.Text = "-";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.InterferometerButtonGroup = uibuttongroup(app.UIFigure);
            app.InterferometerButtonGroup.Title = 'Interferometer';
            app.SagnacButton = uiradiobutton(app.InterferometerButtonGroup);
            app.SagnacButton.Text = 'Sagnac';
            app.SagnacButton.Value = true;
            app.MZIButton = uiradiobutton(app.InterferometerButtonGroup);
            app.MZIButton.Text = 'MZI';
            app.ReadingSwitchLabel = uilabel(app.UIFigure);
            app.ReadingSwitchLabel.Text = 'Reading';
            app.ReadingSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReadingSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadingSwitchValueChanged, true);
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = 'HR:';
            app.HRvalue = uilabel(app.UIFigure);
            app.HRvalue.Text = '-';
            app.RRLabel = uilabel(app.UIFigure);
            app.RRLabel.Text = 'RR:';
            app.RRvalue = uilabel(app.UIFigure);
            app.RRvalue.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end